classdef GUI_Ewen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        OpeningWindowTab              matlab.ui.container.Tab
        SovietStyleWeightliftingProgramAppLabel  matlab.ui.control.Label
        LoadexistingWeightliftingProgramButton  matlab.ui.control.Button
        GeneratenewWeightliftingProgramButton  matlab.ui.control.Button
        Image_2                       matlab.ui.control.Image
        Program_GeneratorTab          matlab.ui.container.Tab
        SovietStyleWeightliftingProgramAppLabel_2  matlab.ui.control.Label
        HowmanydaysperweekDropDown    matlab.ui.control.DropDown
        HowmanydaysperweekLabel       matlab.ui.control.Label
        TypeofProgramButtonGroup      matlab.ui.container.ButtonGroup
        SquatProgramButton            matlab.ui.control.RadioButton
        WeightliftingProgramButton    matlab.ui.control.RadioButton
        OurGainsLabel                 matlab.ui.control.Label
        BestthingtocomeoutofcommunismLabel  matlab.ui.control.Label
        GenerateProgramComradeButton  matlab.ui.control.Button
        rmForntSquatEditField         matlab.ui.control.NumericEditField
        rmForntSquatEditFieldLabel    matlab.ui.control.Label
        rmBacksquatEditField          matlab.ui.control.NumericEditField
        rmBacksquatEditFieldLabel     matlab.ui.control.Label
        rmCleanJerkEditField          matlab.ui.control.NumericEditField
        rmCleanJerkEditFieldLabel     matlab.ui.control.Label
        rmSnatchEditField             matlab.ui.control.NumericEditField
        rmSnatchEditFieldLabel        matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        Program_ViewerTab             matlab.ui.container.Tab
        TrainingdayLabel              matlab.ui.control.Label
        ProgramTable                  matlab.ui.control.Table
        DayEditFieldLabel             matlab.ui.control.Label
        DayEditField                  matlab.ui.control.NumericEditField
        WeekEditField                 matlab.ui.control.NumericEditField
        WeekEditFieldLabel            matlab.ui.control.Label
        DayDoneButton                 matlab.ui.control.Button
        RecordboardButton             matlab.ui.control.Button
        SaveTPforcurrentDayButton     matlab.ui.control.Button
        VariableTable                 matlab.ui.control.Table
        Image_3                       matlab.ui.control.Image
        RecordboardTab                matlab.ui.container.Tab
        Image2                        matlab.ui.control.Image
        RecordboardLabel              matlab.ui.control.Label
        MakenewProgramButton          matlab.ui.control.Button
        BacktoProgramViewerButton     matlab.ui.control.Button
        UpdateMaxesButton             matlab.ui.control.Button
        rmFrontSquatEditField2        matlab.ui.control.NumericEditField
        rmFrontSquatEditFieldLabel    matlab.ui.control.Label
        rmBackSquatEditField2         matlab.ui.control.NumericEditField
        rmBackSquatEditFieldLabel     matlab.ui.control.Label
        rmCleanandJerkEditField2      matlab.ui.control.NumericEditField
        rmCleanandJerkEditFieldLabel  matlab.ui.control.Label
        rmSnatchEditField2            matlab.ui.control.NumericEditField
        rmSnatchLabel                 matlab.ui.control.Label
        RMTimeline                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateProgramComradeButton
        function GenerateProgramComradeButtonPushed(app, event)
       %% Get progress file, if it exists, or make a new one 
       % get path to app directory
       base_dir = fileparts(mfilename('fullpath'));

       % get full path to progress file
       file_path = fullfile(base_dir, 'trainingsprogress.mat');

       % load progress, if it exists
       if exist(file_path, 'file')
           load(file_path, "progress");
       else
           progress = struct();
       end
        
        %% Well done message 
        uialert(app.UIFigure, "Without effort, you can't even pull a fish out of the pond (Soviet Saying)", ...
            "Your Program will be ready shortly", "Icon","success");

        %% Getting the values from the edit fields
        Value_S = app.rmSnatchEditField.Value;
        Value_CnJ = app.rmCleanJerkEditField.Value;
        Value_BS = app.rmBacksquatEditField.Value;
        Value_FS = app.rmForntSquatEditField.Value;

        %% Deciding which program will be generated
        if app.TypeofProgramButtonGroup.SelectedObject.Text == "Weightlifting Program"
            if string(app.HowmanydaysperweekDropDown.Value) == "3 Days per week"
                program = Generate_TP_3_day(Value_S, Value_CnJ, Value_BS, Value_FS);
            else
                program = Generate_TP_4_day(Value_S, Value_CnJ, Value_BS, Value_FS);
            end
        else
            if string(app.HowmanydaysperweekDropDown.Value) == "3 Days per week"
                program = Generate_SQ_3_day(Value_S, Value_CnJ, Value_BS);
            else
                program = Generate_SQ_4_day(Value_S, Value_CnJ, Value_BS, Value_FS);
            end
        end


        %% Update only specific fields in progress
        
        Type = strcat(app.TypeofProgramButtonGroup.SelectedObject.Text, " ", ...
            string(app.HowmanydaysperweekDropDown.Value));

        progress.Type = Type;          % Update Type of Program
        progress.week = 1;             % Reset to Week 1
        progress.Day = 1;              % Reset to Day 1

        % Update One Rep Max values
        progress.OneRepMax.S = Value_S;       
        progress.OneRepMax.CnJ = Value_CnJ;   
        progress.OneRepMax.BS = Value_BS;     
        progress.OneRepMax.FS = Value_FS;     

        %% Maintain history of One Rep Max values
        % Get current date
        current_date = datetime('now', 'Format', 'dd-MMM-uuuu');

        % Append new values to history if it exists, else initialize
        if isfield(progress, 'OneRepMaxHistory')
            progress.OneRepMaxHistory.S(end+1) = Value_S;
            progress.OneRepMaxHistory.CnJ(end+1) = Value_CnJ;
            progress.OneRepMaxHistory.BS(end+1) = Value_BS;
            progress.OneRepMaxHistory.FS(end+1) = Value_FS;
            progress.OneRepMaxHistory.Date(end+1) = current_date;
        else
            progress.OneRepMaxHistory.S = Value_S;
            progress.OneRepMaxHistory.CnJ = Value_CnJ;
            progress.OneRepMaxHistory.BS = Value_BS;
            progress.OneRepMaxHistory.FS = Value_FS;
            progress.OneRepMaxHistory.Date = current_date;
        end

        %% Save the updated progress struct
        base_dir = fileparts(mfilename('fullpath'));
        save(fullfile(base_dir, 'trainingsprogress.mat'), 'progress');


        %% Go to Program Viewer
        app.TabGroup.SelectedTab = app.Program_ViewerTab;


        %% Show current day
        
        field_Name = sprintf('week%d', progress.week);
        current_week = program.(field_Name);

        % makes sure that only strings get in (else it doesnt want to work)
        first_column = string(current_week{:, 1}); 

        % gets the current trainingday and finds the starting index
        current_day = sprintf('Day %d', progress.Day);

        start_Idx = find(strcmp(first_column, current_day), 1);

        % Finds the next day index 
        next_Day_Idx = find(contains(first_column(start_Idx+1:end), "Day"), 1);

        if ~isempty(next_Day_Idx)
            end_Idx = start_Idx + next_Day_Idx - 1; 
        else
            end_Idx = size(current_week, 1);
        end

        % gets the table of the current day and displays it in the UI table
        trainingday = current_week(start_Idx:end_Idx, :);
        app.VariableTable.Data = trainingday;
        

        %% now we want to know, what the day consists of

        info_table = Get_Volume_Intensity(trainingday, progress);
        
        % displays the volume in tonage, total reps and the intensity of
        % the session as relative intensity

        app.ProgramTable.Data = info_table;
        app.ProgramTable.ColumnName = info_table.Properties.VariableNames;
        app.ProgramTable.RowName = info_table.Properties.RowNames;  
        
        % this just displays the current day / week
        app.WeekEditField.Value = progress.week;
        app.DayEditField.Value = progress.Day;

        end

        % Button pushed function: SaveTPforcurrentDayButton
        function SaveTPforcurrentDayButtonPushed(app, event)
       % Get directory of the app
        base_dir = fileparts(mfilename('fullpath'));
        
        % Load progress data
        file_path = fullfile(base_dir, "trainingsprogress.mat");
        
        load(file_path, "progress");
        
        % Get plan from GUI
        Soviet_Weightlifting_Program = app.VariableTable.Data;
        
        % Generate filename
        TP_name = string(progress.Type);
        filename = TP_name + sprintf("_week%d_day%d.xlsx", progress.week, progress.Day);
        file_path_table = fullfile(base_dir, filename);
        
        % Save training plan as table
        writetable(Soviet_Weightlifting_Program, file_path_table);
       

        end

        % Button pushed function: GeneratenewWeightliftingProgramButton
        function GeneratenewWeightliftingProgramButtonPushed(app, event)
        % change to prgram generator
        app.TabGroup.SelectedTab = app.Program_GeneratorTab;
        end

        % Button pushed function: LoadexistingWeightliftingProgramButton
        function LoadexistingWeightliftingProgramButtonPushed(app, event)
        %% load progress
        % get the path the app
        base_dir = fileparts(mfilename('fullpath'));

        % full path to the progress file 
        file_path = fullfile(base_dir, 'trainingsprogress.mat');
        
        %% the button should only work, if the progress file already exists
        if exist(file_path, 'file')
            % change to prgram viewer and load existing program
            app.TabGroup.SelectedTab = app.Program_ViewerTab;
            load(file_path, "progress");
        else
            app.LoadexistingWeightliftingProgramButton.Enable = 'off';
            uialert(app.UIFigure, sprintf('You have no program yet comrade... \n\n ... put the vodka aside and generate a new proram'), ... 
                'Warning', 'Icon', 'warning');
            return;
        end
        
        %% load current program

        if progress.Type == "Weightlifting Program 3 Days per week"
            program = Generate_TP_3_day(progress.OneRepMax.S, progress.OneRepMax.CnJ, ...
                progress.OneRepMax.BS, progress.OneRepMax.FS);
        elseif progress.Type == "Weightlifting Program 4 Days per week"
            program = Generate_TP_4_day(progress.OneRepMax.S, progress.OneRepMax.CnJ, ...
                progress.OneRepMax.BS, progress.OneRepMax.FS);
        elseif progress.Type == "Squat Program 3 Days per week"
            program = Generate_SQ_3_day(progress.OneRepMax.S, progress.OneRepMax.CnJ, ...
                progress.OneRepMax.BS);
        elseif progress.Type == "Squat Program 4 Days per week"
            program = Generate_SQ_4_day(progress.OneRepMax.S, progress.OneRepMax.CnJ, ...
                progress.OneRepMax.BS, progress.OneRepMax.FS);
        end

        %% Show current day
        
        field_name = sprintf('week%d', progress.week);
        current_week = program.(field_name);

        % makes sure that only strings get in (else it doesnt want to work)
        first_column = strtrim(string(current_week{:, 1})); 

        % gets the current trainingday and finds the starting index
        current_day = sprintf('Day %d', progress.Day);

        start_Idx = find(strcmp(first_column, current_day), 1);

       % Finds the next day index 
       next_Day_Idx = find(contains(first_column(start_Idx+1:end), "Day"), 1);

        if ~isempty(next_Day_Idx)
            endIdx = start_Idx + next_Day_Idx - 1; 
        else
            endIdx = size(current_week, 1);
        end

        % gets the table of the current day and displays it in the UI table
        trainingday = current_week(start_Idx:endIdx, :);
        app.VariableTable.Data = trainingday;
        

        %% now we want to know, what the day consists of

        info_table = Get_Volume_Intensity(trainingday, progress);
        
        % displays the volume in tonage, total reps and the intensity of
        % the session as relative intensity

        app.ProgramTable.Data = info_table;
        app.ProgramTable.ColumnName = info_table.Properties.VariableNames;
        app.ProgramTable.RowName = info_table.Properties.RowNames;  
        
        % this just displays the current day / week
        app.WeekEditField.Value = progress.week;
        app.DayEditField.Value = progress.Day;
            
        end

        % Button pushed function: RecordboardButton
        function RecordboardButtonPushed(app, event)
        app.TabGroup.SelectedTab = app.RecordboardTab;
            
        % Hole den Pfad zur App-Datei (Projektordner)
        base_dir = fileparts(mfilename('fullpath'));

        % Voller Pfad zur Fortschrittsdatei
        file_path = fullfile(base_dir, 'trainingsprogress.mat');

        % Datei laden
        load(file_path, "progress");

        %% showing current maxes
        app.rmCleanandJerkEditField2.Value = progress.OneRepMax.CnJ;
        app.rmSnatchEditField2.Value = progress.OneRepMax.S;
        app.rmBackSquatEditField2.Value = progress.OneRepMax.BS;
        app.rmFrontSquatEditField2.Value = progress.OneRepMax.FS;

        % Extract data for plotting
        dates = progress.OneRepMaxHistory.Date;
        SnatchHistory = progress.OneRepMaxHistory.S;
        CnJHistory = progress.OneRepMaxHistory.CnJ;
        BSHistory = progress.OneRepMaxHistory.BS;
        FSHistory = progress.OneRepMaxHistory.FS;

        % Clear previous plot
        cla(app.RMTimeline);

        % Plot each 1RM category with markers
        hold(app.RMTimeline, 'on');
        plot(app.RMTimeline, dates, SnatchHistory, '-o', 'DisplayName', 'Snatch', 'LineWidth', 1.5);
        plot(app.RMTimeline, dates, CnJHistory, '-o', 'DisplayName', 'Clean & Jerk', 'LineWidth', 1.5);
        plot(app.RMTimeline, dates, BSHistory, '-o', 'DisplayName', 'Back Squat', 'LineWidth', 1.5);
        plot(app.RMTimeline, dates, FSHistory, '-o', 'DisplayName', 'Front Squat', 'LineWidth', 1.5);
        hold(app.RMTimeline, 'off');
       
        %% setting limits to improve plot readability
        min_lim = min([min(progress.OneRepMaxHistory.S), ...
            min(progress.OneRepMaxHistory.CnJ), ...
            min(progress.OneRepMaxHistory.BS), ...
            min(progress.OneRepMaxHistory.FS)]) - 10;
        max_lim = max([max(progress.OneRepMaxHistory.S), ...
            max(progress.OneRepMaxHistory.CnJ), ...
            max(progress.OneRepMaxHistory.BS), ...
            max(progress.OneRepMaxHistory.FS)]) + 10;


        % Customize plot appearance
        title(app.RMTimeline, '1RM Progress Timeline');
        xlabel(app.RMTimeline, 'Date');
        ylabel(app.RMTimeline, 'Weight (kg)');
        legend(app.RMTimeline, 'show'); % Show legend 
        ylim(app.RMTimeline, [min_lim, max_lim]); % setting ylimits 
        grid(app.RMTimeline, 'on'); % Enable grid

        end

        % Button pushed function: DayDoneButton
        function DayDoneButtonPushed(app, event)
        % get project dir 
        base_dir = fileparts(mfilename('fullpath'));

        % get full path to progress file
        file_path = fullfile(base_dir, 'trainingsprogress.mat');

        %% load progress
        load(file_path, "progress");
        
        %% Well done message 
        uialert(app.UIFigure, "Great work Comrade!", "Well Done", "Icon","success");

        %% updating current day and week depending on the program
            
        if progress.Type == "Weightlifting Program 3 Days per week" ...
                || progress.Type == "Squat Program 3 Days per week"
            if progress.Day == 3
               progress.Day = 1;
               progress.week = progress.week + 1;
            else
                progress.Day = progress.Day + 1;
            end

        elseif progress.Type == "Weightlifting Program 4 Days per week" ...
                || progress.Type == "Squat Program 4 Days per week"

            if progress.Day == 4
                progress.Day = 1;
                progress.week = progress.week + 1;
            else
                progress.Day = progress.Day + 1;
            end
        end
            
        %% load current program

        if progress.Type == "Weightlifting Program 3 Days per week"
            program = Generate_TP_3_day(progress.OneRepMax.S, progress.OneRepMax.CnJ, progress.OneRepMax.BS, progress.OneRepMax.FS);
        elseif progress.Type == "Weightlifting Program 4 Days per week"
            program = Generate_TP_4_day(progress.OneRepMax.S, progress.OneRepMax.CnJ, progress.OneRepMax.BS, progress.OneRepMax.FS);
        elseif progress.Type == "Squat Program 3 Days per week"
            program = Generate_SQ_3_day(progress.OneRepMax.S, progress.OneRepMax.CnJ, progress.OneRepMax.BS);
        elseif progress.Type == "Squat Program 4 Days per week"
            program = Generate_SQ_4_day(progress.OneRepMax.S, progress.OneRepMax.CnJ, progress.OneRepMax.BS, progress.OneRepMax.FS);
        end

        %% Show current day
        % Check if the program is already completed
        if ((progress.Type == "Weightlifting Program 3 Days per week" || ...
             progress.Type == "Weightlifting Program 4 Days per week") && ...
             progress.week >= 13) || ...
           ((progress.Type == "Squat Program 3 Days per week" || ...
             progress.Type == "Squat Program 4 Days per week") && ...
             progress.week >= 7)
            
            uialert(app.UIFigure, "Congratulation on the completion of this prgoram, now back to factory!", ...
                "Well Done", "Icon","success");
            app.TabGroup.SelectedTab = app.Program_GeneratorTab;
        
        else

            %% Load current training day
            field_name = sprintf('week%d', progress.week);
            current_week = program.(field_name);
        
            % Ensure the first column contains strings
            first_column = strtrim(string(current_week{:, 1})); 
        
            % Select current training day
            current_day = sprintf('Day %d', progress.Day);
        
            % Find start index
            start_Idx = find(strcmp(first_column, current_day), 1);
        
            % Find next day's index or end of table
            next_Day_Idx = find(contains(first_column(start_Idx+1:end), "Day"), 1);
        
            if ~isempty(next_Day_Idx)
                endIdx = start_Idx + next_Day_Idx - 1; 
            else
                endIdx = size(current_week, 1);
            end
        
            % Extract data for the day
            trainingday = current_week(start_Idx:endIdx, :);
        
            % Set extracted data in UI table
            app.VariableTable.Data = trainingday;
        
            app.ProgramTable.Data = [];
            info_table = Get_Volume_Intensity(trainingday, progress);
            
            %% get the volume and intensity markers  
            app.ProgramTable.Data = info_table;
            app.ProgramTable.ColumnName = info_table.Properties.VariableNames;
            app.ProgramTable.RowName = info_table.Properties.RowNames;  
            
            %% save progress
            base_dir = fileparts(mfilename('fullpath'));
            save(fullfile(base_dir, 'trainingsprogress.mat'), 'progress');

            % update day and week
            app.WeekEditField.Value = progress.week;
            app.DayEditField.Value = progress.Day;
        end

        end

        % Button pushed function: UpdateMaxesButton
        function UpdateMaxesButtonPushed(app, event)
        % Load existing progress or create a new struct
        % Hole den Pfad zur App-Datei (Projektordner)
        base_dir = fileparts(mfilename('fullpath'));

        % Voller Pfad zur Fortschrittsdatei
        file_path = fullfile(base_dir, 'trainingsprogress.mat');

        % Datei laden
        load(file_path, "progress");
 
        %% if its a new max, congratulate comrade

        if app.rmSnatchEditField2.Value > progress.OneRepMaxHistory.S(end) ...
         || app.rmCleanandJerkEditField2.Value > progress.OneRepMaxHistory.CnJ(end) ...
         || app.rmBackSquatEditField2.Value > progress.OneRepMaxHistory.BS(end) ...
         || app.rmFrontSquatEditField2.Value > progress.OneRepMaxHistory.FS(end)

            uialert(app.UIFigure, "Awesome, a new 1 RM, now back to work comrade!", ...
                "Congratulations!", "Icon","success");
       
        end

        % Get current date
        current_date = datetime('now', 'Format', 'dd-MMM-uuuu'); 

        %% Append new values to history
        progress.OneRepMaxHistory.S(end + 1) = app.rmSnatchEditField2.Value;
        progress.OneRepMaxHistory.CnJ(end + 1) = app.rmCleanandJerkEditField2.Value;
        progress.OneRepMaxHistory.BS(end + 1) = app.rmBackSquatEditField2.Value;
        progress.OneRepMaxHistory.FS(end + 1) = app.rmFrontSquatEditField2.Value;
        progress.OneRepMaxHistory.Date(end + 1) = current_date; % Store date
       
        % Clear previous plot
        cla(app.RMTimeline);
        
        %% Update the latest 1RM values
        progress.OneRepMax.S = app.rmSnatchEditField2.Value;
        progress.OneRepMax.CnJ = app.rmCleanandJerkEditField2.Value;
        progress.OneRepMax.BS = app.rmBackSquatEditField2.Value;
        progress.OneRepMax.FS = app.rmFrontSquatEditField2.Value;

        %% Extract data for plotting
        dates = progress.OneRepMaxHistory.Date;
        Snatch_history = progress.OneRepMaxHistory.S;
        CnJ_history = progress.OneRepMaxHistory.CnJ;
        BS_history = progress.OneRepMaxHistory.BS;
        FS_history = progress.OneRepMaxHistory.FS;

        %% Plot each 1RM category with markers
        hold(app.RMTimeline, 'on');
        plot(app.RMTimeline, dates, Snatch_history, '-o', 'DisplayName', 'Snatch', 'LineWidth', 1.5);
        plot(app.RMTimeline, dates, CnJ_history, '-o', 'DisplayName', 'Clean & Jerk', 'LineWidth', 1.5);
        plot(app.RMTimeline, dates, BS_history, '-o', 'DisplayName', 'Back Squat', 'LineWidth', 1.5);
        plot(app.RMTimeline, dates, FS_history, '-o', 'DisplayName', 'Front Squat', 'LineWidth', 1.5);
        hold(app.RMTimeline, 'off');
        
        %% setting limits to improve plot readability
        min_lim = min([min(progress.OneRepMaxHistory.S), ...
            min(progress.OneRepMaxHistory.CnJ), ...
            min(progress.OneRepMaxHistory.BS), ...
            min(progress.OneRepMaxHistory.FS)]) - 10;
        max_lim = max([max(progress.OneRepMaxHistory.S), ...
            max(progress.OneRepMaxHistory.CnJ), ...
            max(progress.OneRepMaxHistory.BS), ...
            max(progress.OneRepMaxHistory.FS)]) + 10;
    
        %% Customize plot appearance
        title(app.RMTimeline, '1RM Progress Timeline');
        xlabel(app.RMTimeline, 'Date');
        ylabel(app.RMTimeline, 'Weight (kg)');
        legend(app.RMTimeline, 'show'); % Show legend
        grid(app.RMTimeline, 'on'); % Enable grid
        ylim(app.RMTimeline, [min_lim, max_lim]); % setting ylimits 

        %% save everything
        base_dir = fileparts(mfilename('fullpath'));
        save(fullfile(base_dir, 'trainingsprogress.mat'), 'progress');


        end

        % Button pushed function: MakenewProgramButton
        function MakenewProgramButtonPushed(app, event)
            %redirect to program generator
            app.TabGroup.SelectedTab = app.Program_GeneratorTab;

        end

        % Button pushed function: BacktoProgramViewerButton
        function BacktoProgramViewerButtonPushed(app, event)
        %redirect to program viewer with new maxes

        app.TabGroup.SelectedTab = app.Program_ViewerTab;

        % Hole den Pfad zur App-Datei (Projektordner)
        base_dir = fileparts(mfilename('fullpath'));

        % Voller Pfad zur Fortschrittsdatei
        file_path = fullfile(base_dir, 'trainingsprogress.mat');

        % Datei laden
        load(file_path, "progress");
            
        %% load current program

        if progress.Type == "Weightlifting Program 3 Days per week"
            program = Generate_TP_3_day(progress.OneRepMax.S, progress.OneRepMax.CnJ, progress.OneRepMax.BS, progress.OneRepMax.FS);
        elseif progress.Type == "Weightlifting Program 4 Days per week"
            program = Generate_TP_4_day(progress.OneRepMax.S, progress.OneRepMax.CnJ, progress.OneRepMax.BS, progress.OneRepMax.FS);
        elseif progress.Type == "Squat Program 3 Days per week"
            program = Generate_SQ_3_day(progress.OneRepMax.S, progress.OneRepMax.CnJ, progress.OneRepMax.BS);
        elseif progress.Type == "Squat Program 4 Days per week"
            program = Generate_SQ_4_day(progress.OneRepMax.S, progress.OneRepMax.CnJ, progress.OneRepMax.BS, progress.OneRepMax.FS);
        end
       
        %% Show current day
        
        field_name = sprintf('week%d', progress.week);
        current_week = program.(field_name);

        % makes sure that only strings get in (else it doesnt want to work)
        first_column = strtrim(string(current_week{:, 1})); 

        % gets the current trainingday and finds the starting index
        current_day = sprintf('Day %d', progress.Day);

        start_Idx = find(strcmp(first_column, current_day), 1);

        % Finds the next day index 
        next_day_Idx = find(contains(first_column(start_Idx+1:end), "Day"), 1);

        if ~isempty(next_day_Idx)
            endIdx = start_Idx + next_day_Idx - 1; 
        else
            endIdx = size(current_week, 1);
        end

        % gets the table of the current day and displays it in the UI table
        current_day = current_week(start_Idx:endIdx, :);
        app.VariableTable.Data = current_day;
        

        %% now we want to know, what the day consists of

        info_table = Get_Volume_Intensity(current_day, progress);
        
        % displays the volume in tonage, total reps and the intensity of
        % the session as relative intensity

        app.ProgramTable.Data = info_table;
        app.ProgramTable.ColumnName = info_table.Properties.VariableNames;
        app.ProgramTable.RowName = info_table.Properties.RowNames;  
        
        % this just displays the current day / week
        app.WeekEditField.Value = progress.week;
        app.DayEditField.Value = progress.Day;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OpeningWindowTab = uitab(app.TabGroup);
            app.OpeningWindowTab.Title = 'Opening Window';
            app.Image_2 = uiimage(app.OpeningWindowTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Communist_Bugs_Bunny.jpg');
            app.GeneratenewWeightliftingProgramButton = uibutton(app.OpeningWindowTab, 'push');
            app.GeneratenewWeightliftingProgramButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratenewWeightliftingProgramButtonPushed, true);
            app.GeneratenewWeightliftingProgramButton.Text = 'Generate new Weightlifting Program';
            app.LoadexistingWeightliftingProgramButton = uibutton(app.OpeningWindowTab, 'push');
            app.LoadexistingWeightliftingProgramButton.ButtonPushedFcn = createCallbackFcn(app, @LoadexistingWeightliftingProgramButtonPushed, true);
            app.LoadexistingWeightliftingProgramButton.Text = 'Load existing Weightlifting Program';
            app.SovietStyleWeightliftingProgramAppLabel = uilabel(app.OpeningWindowTab);
            app.SovietStyleWeightliftingProgramAppLabel.Text = 'Soviet Style Weightlifting Program App';
            app.Program_GeneratorTab = uitab(app.TabGroup);
            app.Program_GeneratorTab.Title = 'Program_Generator';
            app.Image = uiimage(app.Program_GeneratorTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Communist_Bugs_Bunny.jpg');
            app.rmSnatchEditFieldLabel = uilabel(app.Program_GeneratorTab);
            app.rmSnatchEditFieldLabel.Text = '1rm Snatch';
            app.rmSnatchEditField = uieditfield(app.Program_GeneratorTab, 'numeric');
            app.rmSnatchEditField.Limits = [0 Inf];
            app.rmCleanJerkEditFieldLabel = uilabel(app.Program_GeneratorTab);
            app.rmCleanJerkEditFieldLabel.Text = '1rm Clean & Jerk';
            app.rmCleanJerkEditField = uieditfield(app.Program_GeneratorTab, 'numeric');
            app.rmCleanJerkEditField.Limits = [0 Inf];
            app.rmBacksquatEditFieldLabel = uilabel(app.Program_GeneratorTab);
            app.rmBacksquatEditFieldLabel.Text = '1rm Back squat';
            app.rmBacksquatEditField = uieditfield(app.Program_GeneratorTab, 'numeric');
            app.rmBacksquatEditField.Limits = [0 Inf];
            app.rmForntSquatEditFieldLabel = uilabel(app.Program_GeneratorTab);
            app.rmForntSquatEditFieldLabel.Text = '1rm Fornt Squat';
            app.rmForntSquatEditField = uieditfield(app.Program_GeneratorTab, 'numeric');
            app.rmForntSquatEditField.Limits = [0 Inf];
            app.GenerateProgramComradeButton = uibutton(app.Program_GeneratorTab, 'push');
            app.GenerateProgramComradeButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateProgramComradeButtonPushed, true);
            app.GenerateProgramComradeButton.Text = 'Generate Program Comrade';
            app.BestthingtocomeoutofcommunismLabel = uilabel(app.Program_GeneratorTab);
            app.BestthingtocomeoutofcommunismLabel.Text = 'Best thing to come out of communism';
            app.OurGainsLabel = uilabel(app.Program_GeneratorTab);
            app.OurGainsLabel.Text = 'Our Gains!';
            app.TypeofProgramButtonGroup = uibuttongroup(app.Program_GeneratorTab);
            app.TypeofProgramButtonGroup.Title = 'Type of Program';
            app.WeightliftingProgramButton = uiradiobutton(app.TypeofProgramButtonGroup);
            app.WeightliftingProgramButton.Text = 'Weightlifting Program';
            app.WeightliftingProgramButton.Value = true;
            app.SquatProgramButton = uiradiobutton(app.TypeofProgramButtonGroup);
            app.SquatProgramButton.Text = 'Squat Program';
            app.HowmanydaysperweekLabel = uilabel(app.Program_GeneratorTab);
            app.HowmanydaysperweekLabel.Text = 'How many days per week ? ';
            app.HowmanydaysperweekDropDown = uidropdown(app.Program_GeneratorTab);
            app.HowmanydaysperweekDropDown.Items = {'3 Days per week', '4 Days per week'};
            app.HowmanydaysperweekDropDown.Value = '3 Days per week';
            app.SovietStyleWeightliftingProgramAppLabel_2 = uilabel(app.Program_GeneratorTab);
            app.SovietStyleWeightliftingProgramAppLabel_2.Text = 'Soviet Style Weightlifting Program App';
            app.Program_ViewerTab = uitab(app.TabGroup);
            app.Program_ViewerTab.Title = 'Program_Viewer';
            app.Image_3 = uiimage(app.Program_ViewerTab);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'Communist_Bugs_Bunny.jpg');
            app.VariableTable = uitable(app.Program_ViewerTab);
            app.VariableTable.ColumnName = {'Exercise'; 'Weights/Sets'; ''; ''};
            app.VariableTable.RowName = {};
            app.SaveTPforcurrentDayButton = uibutton(app.Program_ViewerTab, 'push');
            app.SaveTPforcurrentDayButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTPforcurrentDayButtonPushed, true);
            app.SaveTPforcurrentDayButton.Text = 'Save TP for current Day';
            app.RecordboardButton = uibutton(app.Program_ViewerTab, 'push');
            app.RecordboardButton.ButtonPushedFcn = createCallbackFcn(app, @RecordboardButtonPushed, true);
            app.RecordboardButton.Text = 'Recordboard';
            app.DayDoneButton = uibutton(app.Program_ViewerTab, 'push');
            app.DayDoneButton.ButtonPushedFcn = createCallbackFcn(app, @DayDoneButtonPushed, true);
            app.DayDoneButton.Text = 'Day Done';
            app.WeekEditFieldLabel = uilabel(app.Program_ViewerTab);
            app.WeekEditFieldLabel.Text = 'Week';
            app.WeekEditField = uieditfield(app.Program_ViewerTab, 'numeric');
            app.WeekEditField.Editable = 'off';
            app.DayEditField = uieditfield(app.Program_ViewerTab, 'numeric');
            app.DayEditField.Editable = 'off';
            app.DayEditFieldLabel = uilabel(app.Program_ViewerTab);
            app.DayEditFieldLabel.Text = 'Day';
            app.ProgramTable = uitable(app.Program_ViewerTab);
            app.ProgramTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ProgramTable.RowName = {};
            app.TrainingdayLabel = uilabel(app.Program_ViewerTab);
            app.TrainingdayLabel.Text = 'Trainingday';
            app.RecordboardTab = uitab(app.TabGroup);
            app.RecordboardTab.Title = 'Recordboard';
            app.RMTimeline = uiaxes(app.RecordboardTab);
            title(app.RMTimeline, 'Title')
            xlabel(app.RMTimeline, 'X')
            ylabel(app.RMTimeline, 'Y')
            zlabel(app.RMTimeline, 'Z')
            app.rmSnatchLabel = uilabel(app.RecordboardTab);
            app.rmSnatchLabel.Text = '1rm Snatch';
            app.rmSnatchEditField2 = uieditfield(app.RecordboardTab, 'numeric');
            app.rmSnatchEditField2.Limits = [0 Inf];
            app.rmCleanandJerkEditFieldLabel = uilabel(app.RecordboardTab);
            app.rmCleanandJerkEditFieldLabel.Text = '1rm Clean and Jerk';
            app.rmCleanandJerkEditField2 = uieditfield(app.RecordboardTab, 'numeric');
            app.rmCleanandJerkEditField2.Limits = [0 Inf];
            app.rmBackSquatEditFieldLabel = uilabel(app.RecordboardTab);
            app.rmBackSquatEditFieldLabel.Text = '1rm Back Squat';
            app.rmBackSquatEditField2 = uieditfield(app.RecordboardTab, 'numeric');
            app.rmBackSquatEditField2.Limits = [0 Inf];
            app.rmFrontSquatEditFieldLabel = uilabel(app.RecordboardTab);
            app.rmFrontSquatEditFieldLabel.Text = '1rm Front Squat';
            app.rmFrontSquatEditField2 = uieditfield(app.RecordboardTab, 'numeric');
            app.rmFrontSquatEditField2.Limits = [0 Inf];
            app.UpdateMaxesButton = uibutton(app.RecordboardTab, 'push');
            app.UpdateMaxesButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateMaxesButtonPushed, true);
            app.UpdateMaxesButton.Text = 'Update Maxes';
            app.BacktoProgramViewerButton = uibutton(app.RecordboardTab, 'push');
            app.BacktoProgramViewerButton.ButtonPushedFcn = createCallbackFcn(app, @BacktoProgramViewerButtonPushed, true);
            app.BacktoProgramViewerButton.Text = 'Back to Program Viewer';
            app.MakenewProgramButton = uibutton(app.RecordboardTab, 'push');
            app.MakenewProgramButton.ButtonPushedFcn = createCallbackFcn(app, @MakenewProgramButtonPushed, true);
            app.MakenewProgramButton.Text = 'Make new Program';
            app.RecordboardLabel = uilabel(app.RecordboardTab);
            app.RecordboardLabel.Text = 'Recordboard';
            app.Image2 = uiimage(app.RecordboardTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'hurra.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Ewen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end